classdef MainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ObjectCollisionPanel    matlab.ui.container.Panel
        ArmSlider               matlab.ui.control.Slider
        ArmSliderLabel          matlab.ui.control.Label
        HumanSlider             matlab.ui.control.Slider
        HumanSliderLabel        matlab.ui.control.Label
        EnvironmentPanel        matlab.ui.container.Panel
        ResetEnvironmentButton  matlab.ui.control.Button
        Lamp_2                  matlab.ui.control.Lamp
        Lamp                    matlab.ui.control.Lamp
        TeachSwitch             matlab.ui.control.ToggleSwitch
        TeachSwitchLabel        matlab.ui.control.Label
        TeachTM12Button         matlab.ui.control.Button
        LoadSimulateButton      matlab.ui.control.Button
        TeachTM5Button          matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Handle to the TeachPendant App:
        TeachPendantAppHandle = [];
        TeachPendantTM12AppHandle = [];

        % Data member of this class:
        tm5Robot;           %<! TM5 Robot
        tm12Robot;          %<! TM12 Robot
        human;              %<! Human Object
        arm;                %<! Arm Object

        % Setup the base transform of the two robots:
        tm5BaseTr  = transl(0,0,0.9)*trotz(pi);
        tm12BaseTr = transl(1.95,0.1,0.8);
        humanBaseTr = transl(1.5,-3.6,0) * trotz(-pi/2);
        armBaseTr = transl(2,1.25,0.90);

        % Setup the transform of the shelves in the environment:
        shelf1BaseTr = transl(-2,-2.55,0.1)*trotz(pi/2);
        shelf2BaseTr = transl(-1, -2.55,0.1)*trotz(pi/2);
        shelf3BaseTr = transl(0,-2.55,0.1)*trotz(pi/2);

        % Setup the homing position of the two robots:
        tm5qHome  = [pi, -pi/2, -pi/2, -pi/2, pi/2, 0];
        tm12qHome = [pi, -pi/2, -pi/2, -pi/2, pi/2, 0];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TeachTM5Button
        function TeachTM5ButtonPushed(app, event)
            app.TeachPendantAppHandle = TeachPendant(app, app.tm5Robot.model.getpos);
        end

        % Button pushed function: LoadSimulateButton
        function LoadSimulateButtonPushed(app, event)
            
            % -- Setup the workspace:
            SetupEnvironment(eye(4), app.shelf1BaseTr, app.shelf2BaseTr, app.shelf3BaseTr);
            hold  on;
            
            % -- Setup the robot:
            app.tm5Robot = TM5Mobile(app.tm5BaseTr, app.tm5qHome);
            app.tm12Robot = TM12(app.tm12BaseTr, app.tm12qHome);
            drawnow
            
            % -- Create object human:
            app.human = Human(app.humanBaseTr);
            
            % -- Create an arm object:
            app.arm = Arm(app.armBaseTr);

            % -- Turn on the switch button:
            app.TeachSwitch.Enable = "on";
            
            % -- Turn on the reset button:
            app.ResetEnvironmentButton.Enable = "on";
           
            % -- Turn on the slider:
            % Human Object Slider:
            app.HumanSlider.Enable = "on";
            jointLimit = app.human.model.qlim;
            app.HumanSlider.Limits = jointLimit(1,:);

            % Arm Object Slider:
            app.ArmSlider.Enable = "on";
            jointLimit = rad2deg(app.arm.model.qlim);
            app.ArmSlider.Limits = jointLimit(1,:);

            % -- Turn off the simulate button:
            app.LoadSimulateButton.Enable = "off";

        end

        % Value changed function: TeachSwitch
        function TeachSwitchValueChanged(app, event)
            if strcmp(app.TeachSwitch.Value, 'On')
                % -- Turn on the teach button:
                app.TeachTM5Button.Enable = "on";
                app.TeachTM12Button.Enable = "on";

                % -- Setup the light
                app.Lamp.Color = [0, 0.9, 0];
                app.Lamp_2.Color = [0, 0.9, 0];

            else
                % -- Turn on the teach button:
                app.TeachTM5Button.Enable = "off";
                app.TeachTM12Button.Enable = "off";
                
                % -- Setup the light
                app.Lamp.Color = [0.9, 0, 0];
                app.Lamp_2.Color = [0.9, 0, 0];

                % Close the TeachPendant app for the TM5:
                if ~isempty(app.TeachPendantAppHandle)
                    delete(app.TeachPendantAppHandle);
                    app.TeachPendantAppHandle = [];
                end

                % Close the TeachPendant app for the TM12:
                if ~isempty(app.TeachPendantTM12AppHandle)
                    delete(app.TeachPendantTM12AppHandle);
                    app.TeachPendantTM12AppHandle = [];
                end
    
            end
        end

        % Button pushed function: ResetEnvironmentButton
        function ResetEnvironmentButtonPushed(app, event)
            if ~isempty(figure(1))
                close(1)
            end
            % -- Turn on the simulate button:
            app.LoadSimulateButton.Enable = "on";

            % -- Turn off the reset button:
            app.ResetEnvironmentButton.Enable = "off";

            % -- Turn off the switch button:
            app.TeachSwitch.Enable = "off";
            
            % -- Turn off the slider:
            app.HumanSlider.Enable = "off";
            app.ArmSlider.Enable = "off";

            % Close the TeachPendant app for the TM5:
            if ~isempty(app.TeachPendantAppHandle)
                delete(app.TeachPendantAppHandle);
                app.TeachPendantAppHandle = [];
            end

            % Close the TeachPendant app for the TM12:
            if ~isempty(app.TeachPendantTM12AppHandle)
                delete(app.TeachPendantTM12AppHandle);
                app.TeachPendantTM12AppHandle = [];
            end

        end

        % Button pushed function: TeachTM12Button
        function TeachTM12ButtonPushed(app, event)
            app.TeachPendantTM12AppHandle = TeachPendantTM12(app, app.tm12Robot.model.getpos);
        end

        % Value changed function: HumanSlider
        function HumanSliderValueChanged(app, event)
            currentPose = app.human.model.getpos;
            currentPose(1) = app.HumanSlider.Value;
            app.human.model.animate(currentPose);
        end

        % Value changed function: ArmSlider
        function ArmSliderValueChanged(app, event)
            currentPose = app.arm.model.getpos;
            currentPose(1) = app.ArmSlider.Value;
            app.arm.model.animate(deg2rad(currentPose));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentPanel = uipanel(app.UIFigure);
            app.EnvironmentPanel.Title = 'Environment';
            app.TeachTM5Button = uibutton(app.EnvironmentPanel, 'push');
            app.TeachTM5Button.ButtonPushedFcn = createCallbackFcn(app, @TeachTM5ButtonPushed, true);
            app.TeachTM5Button.Enable = 'off';
            app.TeachTM5Button.Text = 'Teach TM5';
            app.LoadSimulateButton = uibutton(app.EnvironmentPanel, 'push');
            app.LoadSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulateButtonPushed, true);
            app.LoadSimulateButton.Text = 'Load Simulate';
            app.TeachTM12Button = uibutton(app.EnvironmentPanel, 'push');
            app.TeachTM12Button.ButtonPushedFcn = createCallbackFcn(app, @TeachTM12ButtonPushed, true);
            app.TeachTM12Button.Enable = 'off';
            app.TeachTM12Button.Text = 'Teach TM12';
            app.TeachSwitchLabel = uilabel(app.EnvironmentPanel);
            app.TeachSwitchLabel.Enable = 'off';
            app.TeachSwitchLabel.Text = 'Teach';
            app.TeachSwitch = uiswitch(app.EnvironmentPanel, 'toggle');
            app.TeachSwitch.ValueChangedFcn = createCallbackFcn(app, @TeachSwitchValueChanged, true);
            app.TeachSwitch.Enable = 'off';
            app.Lamp = uilamp(app.EnvironmentPanel);
            app.Lamp_2 = uilamp(app.EnvironmentPanel);
            app.ResetEnvironmentButton = uibutton(app.EnvironmentPanel, 'push');
            app.ResetEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEnvironmentButtonPushed, true);
            app.ResetEnvironmentButton.Enable = 'off';
            app.ResetEnvironmentButton.Text = 'Reset Environment';
            app.ObjectCollisionPanel = uipanel(app.UIFigure);
            app.ObjectCollisionPanel.Title = 'Object Collision';
            app.HumanSliderLabel = uilabel(app.ObjectCollisionPanel);
            app.HumanSliderLabel.Text = 'Human';
            app.HumanSlider = uislider(app.ObjectCollisionPanel);
            app.HumanSlider.ValueChangedFcn = createCallbackFcn(app, @HumanSliderValueChanged, true);
            app.HumanSlider.Enable = 'off';
            app.ArmSliderLabel = uilabel(app.ObjectCollisionPanel);
            app.ArmSliderLabel.Text = 'Arm';
            app.ArmSlider = uislider(app.ObjectCollisionPanel);
            app.ArmSlider.ValueChangedFcn = createCallbackFcn(app, @ArmSliderValueChanged, true);
            app.ArmSlider.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end